classdef GUI_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ClassifyimagesButton     matlab.ui.control.Button
        TrainButton              matlab.ui.control.Button
        ResultsTextAreaLabel     matlab.ui.control.Label
        ResultsTextArea          matlab.ui.control.TextArea
        SaveneuralnetworkButton  matlab.ui.control.Button
        LoadneuralnetworkButton  matlab.ui.control.Button
    end


    properties (Access = public)
        network % trained neural network
    end

    methods (Access = public)
    
        function results = trainNetwork(app, trainDir)
            txt = {''};
            indText = 1;
            app.ResultsTextArea.Editable = 'off';
            files = dir([trainDir,'/leaf*/*.tif']);
            n = length(files);
            
            K = 64;
            firstFile = [files(1).folder,'/',files(1).name];
            I = imread([files(1).folder,'/',files(1).name]);
            I = square(I);
            I = imresize(I,[512 512]);
            I = rgb2gray(I);
            features = extractHOGFeatures(I,'CellSize',[K K]);
            hogFeatureSize = length(features);
            
            %% feature extraction        
            inputs = zeros(hogFeatureSize,n);
            targets = zeros(15,n);
            
            tic
            for i=1:n
                folder = files(i).folder;
                fn = files(i).name;
                
                I = imread(strcat(folder,'/',fn));
                I = square(I);
                I = imresize(I,[512 512]);
                I = rgb2gray(I);
                
                [featureVector,hogVisualization] = extractHOGFeatures(I,'CellSize',[K K]);
                inputs(:,i) = featureVector';
                plot(hogVisualization); drawnow;
                
                class = int32(str2num(fn(2:end-9)));
                targets(class,i) = 1;
                
                txt{indText} = sprintf('Extracting features of %s\n',fn);
                app.ResultsTextArea.Value = txt;
                indText = indText + 1;
            end
            toc
            close all;
            
            
            %% train
            
            % 'trainlm' is usually fastest.
            % 'trainbr' takes longer but may be better for challenging problems.
            % 'trainscg' uses less memory. Suitable in low memory situations.
            trainFcn = 'trainscg';  % Scaled conjugate gradient backpropagation.
            
            % Create a Pattern Recognition Network
            hiddenLayerSize = 20;
            net = patternnet(hiddenLayerSize, trainFcn);
            
            % Setup Division of Data for Training, Validation, Testing
            net.divideParam.trainRatio = 70/100;
            net.divideParam.valRatio = 15/100;
            net.divideParam.testRatio = 15/100;
            
            % Train the Network
            [net,tr] = train(net,inputs,targets);
            nntraintool('close');
            app.network = net;
            
            % Test the Network
            outputs = net(inputs);
            [c,cm] = confusion(targets,outputs);
            txt{indText} = sprintf('\naccuracy: %f\n',1-c);
            indText = indText + 1;
            txt{indText} = sprintf('\nTest confusion matrix:\n\n');
            indText = indText + 1;
            strCm = num2str(cm);
            [m n] = size(strCm);
            cellCm = mat2cell(strCm,ones(1,m),[n]);
            txt = [txt, cellCm'];
            app.ResultsTextArea.Value = txt;
        end
        
    end


    methods (Access = private)

        % Button pushed function: ClassifyimagesButton
        function ClassifyimagesButtonPushed(app, event)
            [FILENAMES, PATHNAME] = uigetfile('*.tif','Select images','Multiselect', 'on');
            FILENAMES = [{},FILENAMES];
            n = length(FILENAMES);
            
            K = 64;
            targets = zeros(n,15);
            outputs = zeros(n,15);
            txt = {''};
            indText = 1;
            
            for i=1:n
                fn = FILENAMES{i};
                
                I = imread([PATHNAME,fn]);
                I = square(I);
                I = imresize(I,[512 512]);
                I = rgb2gray(I);
                
                featureVector = extractHOGFeatures(I,'CellSize',[K K]);               
                outputs(i,:) = app.network(featureVector');
                
                class = int32(str2num(fn(2:end-9)));
                targets(i,class) = 1;
                
                txt{indText} = sprintf('Predicting class of %s\n',fn);
                app.ResultsTextArea.Value = txt;
                indText = indText + 1;
            end
            
            if n==1
                txt{1} = sprintf([FILENAMES{1},'\n\n']);
                txt{2} = sprintf('Classify vector\n\n');
                outputs = [[1:15]',outputs'];
                strCm = num2str(outputs);
                [m n] = size(strCm);
                cellCm = mat2cell(strCm,ones(1,m),[n]);
                txt = [txt, cellCm'];
                [m,i] = max(outputs(:,2));
                txt{18} = sprintf(['\nClass: ',int2str(i)]);
            else
                [c,cm] = confusion(targets,outputs);
                txt{indText} = sprintf('\nValidation confusion matrix:\n\n');
                indText = indText + 1;
                strCm = num2str(cm);
                txt{indText} = strCm;
                indText = indText + n;
                txt{indText} = sprintf('accuracy: %f\n',1-c);
            end
            app.ResultsTextArea.Value = txt;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            trainDir = uigetdir();
            trainNetwork(app, trainDir);
        end

        % Button pushed function: SaveneuralnetworkButton
        function SaveneuralnetworkButtonPushed(app, event)
            neuralNetwork = app.network;
            uisave({'neuralNetwork'}, 'neural_network');
        end

        % Button pushed function: LoadneuralnetworkButton
        function LoadneuralnetworkButtonPushed(app, event)
            uiopen('load');
            try
                app.network = neuralNetwork;
            catch
                warning('Network could not be loaded');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ClassifyimagesButton = uibutton(app.UIFigure, 'push');
            app.ClassifyimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyimagesButtonPushed, true);
            app.ClassifyimagesButton.Text = 'Classify images';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.SaveneuralnetworkButton = uibutton(app.UIFigure, 'push');
            app.SaveneuralnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @SaveneuralnetworkButtonPushed, true);
            app.SaveneuralnetworkButton.Text = 'Save neural network';
            app.LoadneuralnetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadneuralnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadneuralnetworkButtonPushed, true);
            app.LoadneuralnetworkButton.Text = 'Load neural network';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_project()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end